classdef calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        val2EditField          matlab.ui.control.NumericEditField
        val2EditFieldLabel     matlab.ui.control.Label
        val1EditField          matlab.ui.control.NumericEditField
        val1EditFieldLabel     matlab.ui.control.Label
        Monitor                matlab.ui.control.EditField
        Button_1               matlab.ui.control.Button
        Button_4               matlab.ui.control.Button
        Button_7               matlab.ui.control.Button
        Button_0               matlab.ui.control.Button
        Button_2               matlab.ui.control.Button
        Button_5               matlab.ui.control.Button
        Button_8               matlab.ui.control.Button
        Button_dot             matlab.ui.control.Button
        Button_3               matlab.ui.control.Button
        Button_6               matlab.ui.control.Button
        Button_9               matlab.ui.control.Button
        Button_equalTo         matlab.ui.control.Button
        Button_addition        matlab.ui.control.Button
        Button_substraction    matlab.ui.control.Button
        Button_multiplication  matlab.ui.control.Button
        Button_division        matlab.ui.control.Button
        Button_percentage      matlab.ui.control.Button
        DeleteButton           matlab.ui.control.Button
        CEButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        cache = ""
        operator = ""
        results
        val1
        val2
    end
    
    methods (Access = private)


        function results = calc(app, operator, val1, val2)
            switch operator 
                case "+"
                    app.results = app.val1 + app.val2;
                case "-"
                    app.results = app.val1 - app.val2;
                case "*"
                    app.results = app.val1 * app.val2;
                case "/"
                    app.results = app.val1 / app.val2;
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_0
        function Button_0Pushed(app, event)
            app.cache = app.cache + "0";
            app.Monitor.Value = app.cache;
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.cache = app.cache + "1";
            app.Monitor.Value = app.cache;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.cache = app.cache + "2";
            app.Monitor.Value = app.cache;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.cache = app.cache + "3";
            app.Monitor.Value = app.cache;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.cache = app.cache + "4";
            app.Monitor.Value = app.cache;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.cache = app.cache + "5";
            app.Monitor.Value = app.cache;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.cache = app.cache + "6";
            app.Monitor.Value = app.cache;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.cache = app.cache + "7";
            app.Monitor.Value = app.cache;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.cache = app.cache + "8";
            app.Monitor.Value = app.cache;
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.cache = app.cache + "9";
            app.Monitor.Value = app.cache;
        end

        % Button pushed function: Button_dot
        function Button_dotPushed(app, event)
            app.cache = app.cache + ".";
            app.Monitor.Value = app.cache;
        end

        % Button pushed function: Button_addition
        function Button_additionPushed(app, event)
            app.operator = "+";
            app.val1 = str2double(app.cache)
            app.val1EditField.Value = app.val1;
            app.cache = "";
        end

        % Button pushed function: Button_substraction
        function Button_substractionPushed(app, event)
            app.operator = "-";
            app.val1 = str2double(app.cache)
            app.val1EditField.Value = app.val1;
            app.cache = "";
        end

        % Button pushed function: Button_multiplication
        function Button_multiplicationPushed(app, event)
            app.operator = "*";
            app.val1 = str2double(app.cache)
            app.val1EditField.Value = app.val1;
            app.cache = "";
        end

        % Button pushed function: Button_division
        function Button_divisionPushed(app, event)
            app.operator = "/";
            app.val1 = str2double(app.cache)
            app.val1EditField.Value = app.val1;
            app.cache = "";
        end

        % Button pushed function: Button_percentage
        function Button_percentagePushed(app, event)

        end

        % Button pushed function: Button_equalTo
        function Button_equalToPushed(app, event)
            app.val2 = str2double(app.cache);
            app.val2EditField.Value = app.val2;
            app.calc(app.operator, app.val1, app.val2);
            
            app.Monitor.Value = string(app.results);
        end

        % Button pushed function: CEButton
        function CEButtonPushed(app, event)
            app.cache = "";
            app.val1 = "";
            app.val1EditField.Value = 0;
            app.val2 = "";
            app.val2EditField.Value = 0;
            app.operator = "";
            app.Monitor.Value = string(0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CEButton = uibutton(app.UIFigure, 'push');
            app.CEButton.ButtonPushedFcn = createCallbackFcn(app, @CEButtonPushed, true);
            app.CEButton.Text = 'CE';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.Text = 'Delete';
            app.Button_percentage = uibutton(app.UIFigure, 'push');
            app.Button_percentage.ButtonPushedFcn = createCallbackFcn(app, @Button_percentagePushed, true);
            app.Button_percentage.Text = '%';
            app.Button_division = uibutton(app.UIFigure, 'push');
            app.Button_division.ButtonPushedFcn = createCallbackFcn(app, @Button_divisionPushed, true);
            app.Button_division.Text = '/';
            app.Button_multiplication = uibutton(app.UIFigure, 'push');
            app.Button_multiplication.ButtonPushedFcn = createCallbackFcn(app, @Button_multiplicationPushed, true);
            app.Button_multiplication.Text = 'x';
            app.Button_substraction = uibutton(app.UIFigure, 'push');
            app.Button_substraction.ButtonPushedFcn = createCallbackFcn(app, @Button_substractionPushed, true);
            app.Button_substraction.Text = '-';
            app.Button_addition = uibutton(app.UIFigure, 'push');
            app.Button_addition.ButtonPushedFcn = createCallbackFcn(app, @Button_additionPushed, true);
            app.Button_addition.Text = '+';
            app.Button_equalTo = uibutton(app.UIFigure, 'push');
            app.Button_equalTo.ButtonPushedFcn = createCallbackFcn(app, @Button_equalToPushed, true);
            app.Button_equalTo.Text = '=';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_dot = uibutton(app.UIFigure, 'push');
            app.Button_dot.ButtonPushedFcn = createCallbackFcn(app, @Button_dotPushed, true);
            app.Button_dot.Text = '.';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_0 = uibutton(app.UIFigure, 'push');
            app.Button_0.ButtonPushedFcn = createCallbackFcn(app, @Button_0Pushed, true);
            app.Button_0.Text = '0';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Monitor = uieditfield(app.UIFigure, 'text');
            app.val1EditFieldLabel = uilabel(app.UIFigure);
            app.val1EditFieldLabel.Text = 'val1';
            app.val1EditField = uieditfield(app.UIFigure, 'numeric');
            app.val2EditFieldLabel = uilabel(app.UIFigure);
            app.val2EditFieldLabel.Text = 'val2';
            app.val2EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end